classdef Vocales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MujerButton                   matlab.ui.control.Button
        HombreButton                  matlab.ui.control.Button
        Grabeunaudiode3segundosLabel  matlab.ui.control.Label
        IntroduzcaunarchivodeaudioLabel  matlab.ui.control.Label
        CargararchivodeaudioButton    matlab.ui.control.Button
        ArchivoEditField              matlab.ui.control.EditField
        NombredelarchivoLabel         matlab.ui.control.Label
        IntroduzcaunaudioLabel        matlab.ui.control.Label
        IdentificacindevocalesLabel   matlab.ui.control.Label
        VocalLabel                    matlab.ui.control.Label
        subtituloLabel                matlab.ui.control.Label
        AnalizaraudioButton           matlab.ui.control.Button
        ReproduciraudioButton         matlab.ui.control.Button
        GrabarunaudioButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Audio_vec    % Description
        frecuencia   % Frecuencia de la señal obtenida
        tiempo       % Duranción del audio a grabar
        tam          % Elemento necesario para la ventana de Hamming
        
        fft_vec
        fft_x
        
        formantes
    end
    
    methods (Access = private)
        
        function GraficarAudioXY(app, x, y)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            plot(app.UIAxes, x, y); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Audio');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
        end
        
        function GraficarAudioY(app)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            plot(app.UIAxes, app.Audio_vec); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Audio');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
        end
        
        function AudioGrabado_Interactivity(app)
            app.ReproduciraudioButton.Visible = 'on';
            app.AnalizaraudioButton.Visible = 'on';
            app.HombreButton.Visible = 'on';
            app.MujerButton.Visible = 'on';
        end
        
        function AudioAnalizado_Interactivity(app)
            app.subtituloLabel.Visible = 'on';
            app.VocalLabel.Visible = 'on';
        end
        
        function Hide_Buttons(app)
            app.ReproduciraudioButton.Visible = 'off';
            app.AnalizaraudioButton.Visible = 'off';
            app.HombreButton.Visible = 'off';
            app.MujerButton.Visible = 'off';
            app.subtituloLabel.Visible = 'off';
            app.VocalLabel.Visible = 'off';
        end
        
        function obtenerFormantes(app)
            %Aplicamos un filtro pasa altos
            preemph = [1 0.63]; 
            x1 = filter(1,preemph,app.fft_vec);
            
            %Calculamos los coeficientes de prediccion lineal
            A = lpc(x1,8); 
            rts = roots(A);
            
            %Conservamos solo las raices que tengan imaginarios positivos y
            %sacamos los angulos de estos
            rts = rts(imag(rts)>=0); 
            angz = atan2(imag(rts),real(rts));
            
            %Convertimos las frecuencias angulares en rad/muestra 
            %representadas por los ángulos a Hz
            %Calculamos los anchos de banda de los formantes
            [frqs,indices] = sort(angz.*(app.frecuencia/(2*pi))); 
            bw = -1/2*(app.frecuencia/(2*pi))*log(abs(rts(indices)));
            
            nn = 1; 
            for kk = 1:length(frqs)     
                if (frqs(kk) > 90 && bw(kk) <400)         
                    formants(nn) = frqs(kk);         
                    nn = nn+1;     
                end 
            end 
            app.formantes = formantes;
            disp(formants);
        end
        
        function compararFormantes(app)
            Vocal_o = [500,	1000];
            
            Vocal_usuario = [app.formantes(1), app.formantes(2)];
            
            Magnitud_o = norm(app.formantes - Vocal_o);
            
            magnitudes = [Magnitud_o];
            
            Prediccion = min(magnitudes);
            
            switch Prediccion
                case Magnitud_o
                    app.VocalLabel.Text = 'O';
            end
            
        end
        
        function Transformada(app)
            Fs = app.frecuencia;           %f de muestreo
            Ts = 1/Fs;          %Periodo de muestreo
            L = app.tam;           %Muestras
            t = (0:L-1).*Ts;    %vector tiempo
            s = app.Audio_vec.*hamming(app.tam);  %Señal
            
            %%Espectro de frecuencias
            y = fft(s);
            P1 = 2.*(abs(y(1:L/2)/2)); %Valores positivos no imaginarios de amplitud original
            
            f = Fs.*(0:(L/2)-1)./L;    %Vector de frecuencias
            
            app.fft_vec = P1;
            app.fft_x = f;
            
            GraficarAudioXY(app, f, P1);
            obtenerFormantes(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarunaudioButton
        function GrabarunaudioButtonPushed(app, event)
            app.frecuencia = 10000;
            app.tiempo = 3;
            app.tam = app.frecuencia*app.tiempo;
            
            Hide_Buttons(app)
            cla(app.UIAxes,'reset');
            % Graba audio por 5 segundos
            recObj = audiorecorder(app.frecuencia, 16, 1);
            disp('Inicio de la grabación')
            app.GrabarunaudioButton.Text = "Grabando audio...";
            recordblocking(recObj, app.tiempo);
            disp('Fin de la grabación');
           
            % Guarda los datos obtenidos en un vector
            app.Audio_vec = getaudiodata(recObj);
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudioY(app);
            app.GrabarunaudioButton.Text = "Grabar un audio";
            AudioGrabado_Interactivity(app);
        end

        % Button pushed function: ReproduciraudioButton
        function ReproduciraudioButtonPushed(app, event)
             % Reproduce la grabación del audio introducido.
             %play(recObj);
             load chirp.mat;
             sound(app.Audio_vec, Fs);
        end

        % Button pushed function: CargararchivodeaudioButton
        function CargararchivodeaudioButtonPushed(app, event)
            %Carga el nombre del archivo del audio
            filename = app.ArchivoEditField.Value;
            load handel.mat
            
            % Guarda el audio y los canales
            audiowrite(filename,app.Audio_vec,Fs);
            
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudioY(app);
            AudioGrabado_Interactivity(app);
        end

        % Button pushed function: AnalizaraudioButton
        function AnalizaraudioButtonPushed(app, event)
            Transformada(app);
            AudioAnalizado_Interactivity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.GrabarunaudioButton = uibutton(app.UIFigure, 'push');
            app.GrabarunaudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarunaudioButtonPushed, true);
            app.GrabarunaudioButton.Text = 'Grabar un audio';
            app.ReproduciraudioButton = uibutton(app.UIFigure, 'push');
            app.ReproduciraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciraudioButtonPushed, true);
            app.ReproduciraudioButton.Visible = 'off';
            app.ReproduciraudioButton.Text = 'Reproducir audio';
            app.AnalizaraudioButton = uibutton(app.UIFigure, 'push');
            app.AnalizaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizaraudioButtonPushed, true);
            app.AnalizaraudioButton.Visible = 'off';
            app.AnalizaraudioButton.Text = 'Analizar audio';
            app.subtituloLabel = uilabel(app.UIFigure);
            app.subtituloLabel.Visible = 'off';
            app.subtituloLabel.Text = 'La vocal que usted dijo es: ';
            app.VocalLabel = uilabel(app.UIFigure);
            app.VocalLabel.Visible = 'off';
            app.VocalLabel.Text = 'A';
            app.IdentificacindevocalesLabel = uilabel(app.UIFigure);
            app.IdentificacindevocalesLabel.Text = 'Identificación de vocales';
            app.IntroduzcaunaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunaudioLabel.Text = 'Introduzca un audio';
            app.NombredelarchivoLabel = uilabel(app.UIFigure);
            app.NombredelarchivoLabel.Text = 'Nombre del archivo:';
            app.ArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.CargararchivodeaudioButton = uibutton(app.UIFigure, 'push');
            app.CargararchivodeaudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivodeaudioButtonPushed, true);
            app.CargararchivodeaudioButton.Text = 'Cargar archivo de audio';
            app.IntroduzcaunarchivodeaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunarchivodeaudioLabel.Text = 'Introduzca un archivo de audio:';
            app.Grabeunaudiode3segundosLabel = uilabel(app.UIFigure);
            app.Grabeunaudiode3segundosLabel.Text = 'Grabe un audio de 3 segundos:';
            app.HombreButton = uibutton(app.UIFigure, 'push');
            app.HombreButton.Visible = 'off';
            app.HombreButton.Text = 'Hombre';
            app.MujerButton = uibutton(app.UIFigure, 'push');
            app.MujerButton.Visible = 'off';
            app.MujerButton.Text = 'Mujer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end